classdef nsMeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeurostimMetaDataEditorUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        ChangeRootFolderMenu       matlab.ui.container.Menu
        ScanFilesMenu              matlab.ui.container.Menu
        ReadContentMenu            matlab.ui.container.Menu
        DataJointMenu              matlab.ui.container.Menu
        ResetMenu                  matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        DataTab                    matlab.ui.container.Tab
        DJLabel                    matlab.ui.control.Label
        DataJointLabel             matlab.ui.control.Label
        showSubjectMetaDefs        matlab.ui.control.Button
        showExpSessionMetaDefs     matlab.ui.control.Button
        showExpMetaDefs            matlab.ui.control.Button
        BrowsePanel                matlab.ui.container.Panel
        NextDay                    matlab.ui.control.Button
        Refresh                    matlab.ui.control.Button
        PreviousDay                matlab.ui.control.Button
        Day                        matlab.ui.control.DropDown
        DayLabel                   matlab.ui.control.Label
        Month                      matlab.ui.control.DropDown
        MonthLabel                 matlab.ui.control.Label
        Year                       matlab.ui.control.DropDown
        YearDropDownLabel          matlab.ui.control.Label
        ChangePanel                matlab.ui.container.Panel
        DataJointCheckBox          matlab.ui.control.CheckBox
        DryRunCheckBox             matlab.ui.control.CheckBox
        Update                     matlab.ui.control.Button
        Fun                        matlab.ui.control.EditField
        FunEditFieldLabel          matlab.ui.control.Label
        MetaDefinitionTag          matlab.ui.control.EditField
        MetaLabel                  matlab.ui.control.Label
        Paradigm                   matlab.ui.control.EditField
        ParadigmEditFieldLabel     matlab.ui.control.Label
        Root                       matlab.ui.control.EditField
        RootLabel                  matlab.ui.control.Label
        Subject                    matlab.ui.control.EditField
        SubjectEditFieldLabel      matlab.ui.control.Label
        AddMetaFieldSubject        matlab.ui.control.Button
        SubjectMeta                matlab.ui.control.Table
        SubjectsLabel              matlab.ui.control.Label
        AddMetaFieldSession        matlab.ui.control.Button
        SessionMeta                matlab.ui.control.Table
        SessionsLabel              matlab.ui.control.Label
        ExperimentsLabel           matlab.ui.control.Label
        AddMetaField               matlab.ui.control.Button
        ExperimentMeta             matlab.ui.control.Table
        SubjectsTab                matlab.ui.container.Tab
        AddSubject                 matlab.ui.control.Button
        DeleteSubject              matlab.ui.control.Button
        AllSubjectsDryRunCheckBox  matlab.ui.control.CheckBox
        AllSubjectsUpdate          matlab.ui.control.Button
        AddMetaFieldAllSubjects    matlab.ui.control.Button
        AllSubjectsMeta            matlab.ui.control.Table
        AllSubjectsLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        originalExperimentMetaData % The Experiment meta data as read from the files on disk
        originalSessionMetaData % The session meta data as read from disk
        originalSubjectMetaData % The subject meta data as read from disk

        originalAllSubjectsMetaData % The subject meta data as read from disk for the all subjects tab.

        savedStateFields = {'Root','Year','Month','Day','Fun'}; % State of thes ui elements will be saved across runs.
        savedMembers= {'years','months','days','djUser','djHost','djPass','djSafemode'}; % Member valuse will be saved across runs.

        years  % Yeas,months and days found below the root. Saved to disk between runs.
        months
        days

        updateNeeded =false;            % Flag that is set when any meta data are changed
        allSubjectsUpdateNeeded =false; % Flag set when meta data on the Subjects tab change
        WARNCOLOR = [0.47,0.67,0.19];   % Buttons that should be pushed get this green color
        OKCOLOR  = [0.96,0.96,0.96];    % Default color for buttons that don't need to be pushed.
        djUser    = 'root';             % User for the DataJoint server.
        djHost    ='localhost';         % Host running the DataJoint SQL server
        djPass    = 'simple';           % Password for the DataJoint SQL server.
        djSafemode = '1';               % Use safemode for Datajoint updates Set to '0' to sidestep manual confirmation of deletes.
        previousDatabase='';            % Name of the database we previously wrote to
        source = 'tree'                 % Browse the 'tree', or the 'database'
    end

    methods (Access = private)
        function refreshUI(app,target)
            % Updatae the UI Table based on the new content (called after
            % loading new data)
            if nargin<2
                % Update all
                targets = {'Experiment','Session','Subject','DJ'};
                for i=1:numel(targets)
                    refreshUI(app,targets{i});
                end
                return;
            end

            switch (target)
                case 'Experiment'
                    if isempty(app.ExperimentMeta.Data)
                        app.ExperimentMeta.ColumnName = {'No Neurostim experiment data found. Change root, date, or push refresh.'};
                    else
                        app.ExperimentMeta.ColumnName = app.ExperimentMeta.Data.Properties.VariableNames;
                        allTrue = true(1,width(app.ExperimentMeta.Data));
                        app.ExperimentMeta.ColumnSortable = allTrue;
                        fixed = ismember(app.ExperimentMeta.Data.Properties.VariableNames,{'id','starttime','bytes','session_date','file','blocks','conditions','nrtrials','stimuli','ptb','matlab','ns'});
                        app.ExperimentMeta.ColumnEditable = allTrue & ~fixed;
                        app.ExperimentMeta.ColumnWidth = repmat({'fit'},[1 width(app.ExperimentMeta.Data)]);
                        app.ExperimentMeta.ColumnWidth{1} = 0;  % Hide the internal ID columm
                        app.ExperimentMeta.ColumnWidth{end} = "auto";  % Auto sclae the comments
                    end
                case 'Session'
                    % Updatae the Session UI Table based on the new content (called after
                    % loading new data)
                    if isempty(app.SessionMeta.Data)
                        app.SessionMeta.ColumnName = {'No session meta data found.'};
                    else
                        app.SessionMeta.ColumnName = app.SessionMeta.Data.Properties.VariableNames;
                        allTrue = true(1,width(app.SessionMeta.Data));
                        app.SessionMeta.ColumnSortable = allTrue;
                        app.SessionMeta.ColumnEditable = allTrue  & ~ismember(app.SessionMeta.Data.Properties.VariableNames,{'id','subject','session_date'});
                        app.SessionMeta.ColumnWidth = repmat({'fit'},[1 width(app.SessionMeta.Data)]);
                        app.SessionMeta.ColumnWidth{1} = 0;  % Hide the internal ID columm
                        app.SessionMeta.ColumnWidth{end} = "auto";  % Auto sclae the comments
                    end
                case 'Subject'
                    % Updatae the Subject UI Table based on the new content (called after
                    % loading new data)
                    if isempty(app.SubjectMeta.Data)
                        app.SubjectMeta.ColumnName = {'No subject meta data found.'};
                    else
                        app.SubjectMeta.ColumnName = app.SubjectMeta.Data.Properties.VariableNames;
                        allTrue = true(1,width(app.SubjectMeta.Data));
                        app.SubjectMeta.ColumnSortable = allTrue;
                        app.SubjectMeta.ColumnEditable = allTrue  & ~ismember(app.SubjectMeta.Data.Properties.VariableNames,{'id','subject'});
                        app.SubjectMeta.ColumnWidth = repmat({'fit'},[1 width(app.SubjectMeta.Data)]);
                        app.SubjectMeta.ColumnWidth{1} = 0;  % Hide the internal ID columm
                        app.SubjectMeta.ColumnWidth{end} = "auto";  % scale
                    end
                case 'AllSubjects'
                    if isempty(app.AllSubjectsMeta.Data)
                        app.AllSubjectsMeta.ColumnName = {'No subject meta data found.'};
                    else
                        app.AllSubjectsMeta.ColumnName = app.AllSubjectsMeta.Data.Properties.VariableNames;
                        allTrue = true(1,width(app.AllSubjectsMeta.Data));
                        app.AllSubjectsMeta.ColumnSortable = allTrue;
                        app.AllSubjectsMeta.ColumnEditable = allTrue  & ~ismember(app.AllSubjectsMeta.Data.Properties.VariableNames,{'id','subject'});
                        app.AllSubjectsMeta.ColumnWidth = repmat({'fit'},[1 width(app.AllSubjectsMeta.Data)]);
                        app.AllSubjectsMeta.ColumnWidth{1} = 0; %Hide 
                        app.AllSubjectsMeta.ColumnWidth{end} = "auto";  %scale
                    end
                case 'DJ'
                    if app.DataJointCheckBox.Value
                        try
                            schema = ns.getSchema;
                            conn = dj.conn;
                            app.DataJointLabel.Text =sprintf('%s/%s',conn.host,schema.dbname);
                        catch mes
                            app.DataJointLabel.Text =sprintf('No connection.');
                            errordlg(sprintf("Could not connect to DataJoint. Check your environment variables."));
                            rethrow(me)
                        end
                        
                    end
            end
            app.Refresh.BackgroundColor = app.OKCOLOR;
        end

        function saveState(app)
            % Save state of the GUI. (Called on exit)
            for i=1:numel(app.savedStateFields)
                setpref('nsMeta',app.savedStateFields{i},app.(app.savedStateFields{i}).Value);
            end
            for i=1:numel(app.savedMembers)
                setpref('nsMeta',app.savedMembers{i},app.(app.savedMembers{i}));
            end

        end

        function loadState(app)
            % Load previous state of the GUI using the Matlab getpref functionality (called on creation)

            for i=1:numel(app.savedMembers)
                if ispref('nsMeta',app.savedMembers{i})
                    app.(app.savedMembers{i}) = getpref('nsMeta',app.savedMembers{i});
                end
            end
            if ~isempty(app.years)
                app.Year.Items = app.years;
                app.Year.Value = getpref('nsMeta','Year');
                app.YearValueChanged;  % Force an update of the Items of the drop downs
            end
            % Now load stored values
            for i=1:numel(app.savedStateFields)
                if ispref('nsMeta',app.savedStateFields{i})
                    try
                        app.(app.savedStateFields{i}).Value = getpref('nsMeta',app.savedStateFields{i});
                    catch
                    end
                end
            end
            if exist('dj.conn','File')
                clear dj.conn  % Force a re-connect on the database (as host/user may have changed).
            end
            app.RefreshPushed;
        end

        function [tExperiment,tSession,tSubject] = nsScanWrapper(app)
            % Read all meta data from the folder defined by the Root and
            % the Year/Month/Day settings.
            root = app.Root.Value;
            if ispc
                root = strrep(root,'/','\');
            end
            session_date = datetime(str2double(app.Year.Value),str2double(app.Month.Value), str2double(app.Day.Value));
            paradigms = app.selectionToArray(app.Paradigm.Value);
            subjects = app.selectionToArray(app.Subject.Value);
            metaTag = app.MetaDefinitionTag.Value;
            switch  (app.source)
                case 'tree'
                    % Browse the data tree.
                    if exist(root,'dir')
                        [tSubject,tSession,tExperiment ,isLocked] = nsScan('verbose',false,'readFileContents',app.ReadContentMenu.Checked,'root',root,'date',session_date,'schedule','d','readJson',true,'paradigm',paradigms,'subject',subjects,'metaDefinitionTag',metaTag);
                        if isLocked.subject
                            app.AddMetaFieldSubject.Enable = "off";
                        else
                            app.AddMetaFieldSubject.Enable = "on";
                        end
                        if isLocked.session
                            app.AddMetaFieldSession.Enable = "off";
                        else
                            app.AddMetaFieldSession.Enable = "on";
                        end
                          if isLocked.experiment
                            app.AddMetaField.Enable = "off";
                        else
                            app.AddMetaField.Enable = "on";
                        end
                        
                    else

                        tSubject=table;
                        tSession=table;
                        tExperiment =table;
                    end
                case 'database'
                    % Query the database (NOT TESTED FULLY)
                    session_date.Format = 'yyyy-MM-dd';
                    thisSessions = ns.Session & struct('session_date',char(session_date));
                    tSession =fetchtable(thisSessions,'*');
                    nrSessions = height(tSession);
                    tSession = sortrows(tSession,{'session_date','subject'},'ascend');
                    tSession = addvars(tSession,strcat('#',string((1:nrSessions)')),'NewVariableNames','id','before',1);
                    tSession = movevars(tSession,{'session_date','subject'},'after',1);
                    meta  = ns.getMeta(thisSessions);
                    tSession = innerjoin(tSession,meta);


                    tSubject = fetchtable(ns.Subject & thisSessions,'*');
                    nrSubjects = height(tSubject);
                    tSubject= sortrows(tSubject,{'subject'},'ascend');
                    tSubject = addvars(tSubject,strcat('#',string((1:nrSubjects)')),'NewVariableNames','id','before',1);
                    tSubject = movevars(tSubject,{'subject'},'after',1);
                    meta  = ns.getMeta(ns.Subject & thisSessions);
                    tSubject = innerjoin(tSubject,meta);


                    tExperiment = fetchtable(ns.Experiment & thisSessions,'*');
                    nrExperiments = height(tExperiment);
                    tExperiment =  sortrows(tExperiment,{'starttime','subject','paradigm'},'ascend');
                    % Assign a unique ID to each row, so that we can match up with the original values after the user resorts.
                    tExperiment = addvars(tExperiment,strcat('#',string((1:nrExperiments)')),'NewVariableNames','id','before',1);
                    tExperiment = movevars(tExperiment,{'session_date','file','starttime','subject','paradigm'},'After',1);
                    expMeta  = ns.getMeta(ns.Experiment & thisSessions);
                    tExperiment = innerjoin(tExperiment,expMeta);


            end
        end

        function [newFullName,provenance]= updateFile(app,row)
            % Update the contents of a Neurostim file and, if needed rename it and its associated files (same
            % prefix); the latter is needed when either the subject or the
            % paradigm changes.  The stored CIC object in the file is updated
            % accordinly, and the change is logged in the provenance field of the
            % JSON file.
            % If nsMeta has read the file contents this also changes the
            % cic object stored in originalExperimentMetaDat table to
            % reflect the new one saved to disk.

            if app.DryRunCheckBox.Value
                dryRunText = '[DRYRUN]';
                dryRun  = true;
            else
                dryRun  = false;
                dryRunText  = '';
            end
            provenance = '';
            ok = true;

            oldFullName = fullfile(app.Root.Value,strrep(app.originalExperimentMetaData.session_date{row},'-','/'),app.originalExperimentMetaData.file(row));
            if ~exist(oldFullName,'file')
                newFullName =oldFullName;
                fprintf('File does not exist %s\n',oldFullName)
                return
            elseif ismember('cic',app.originalExperimentMetaData.Properties.VariableNames)
                % File contents were read and stored. Use cic
                c = app.originalExperimentMetaData{row,'cic'};
                hasRunSeq = true;
            else
                % Read the file now.
                load(oldFullName,'c');
                hasRunSeq = false;
            end

            % Make sure that all plugins were read correctly. if not, a
            % plugin that is not on the path will be called 
            % defaultScalarElement and miss all plugin information. Saving
            % that object back to disk would permanently lose that
            % information. 
            if ismember('defaultScalarElement',{c.pluginOrder.name})
                errordlg('Not all information in the file was read (plugins not on the path?) and can therefore not be renamed/saved.')
                newFullName = oldFullName;
                return;
            end
            if hasRunSeq
                % Write the current run and seq values to the cic object
                oldRun = c.runNr;
                oldSeq = c.seqNr;
                c.runNr = app.ExperimentMeta.Data{row,'run'};
                c.seqNr = app.ExperimentMeta.Data{row,'seq'};
            end
            oldSubject = c.subject;
            % Write current subject and paradigm to CIC
            c.subject = char(app.ExperimentMeta.Data{row,'subject'});
            isSubjectChange = ~strcmpi(oldSubject,c.subject);
            c.paradigm = char(app.ExperimentMeta.Data{row,'paradigm'});

            % Create the new file name  (which could be the same as the
            % old if subject/paradigm did not change)
            [folder,oldPrefix] = fileparts(oldFullName);
            newPrefix = sprintf('%s.%s.%s',c.subject,c.paradigm,strrep(app.ExperimentMeta.Data{row,'starttime'},':',''));

            isFileNameChange = ~strcmpi(newPrefix,oldPrefix);
            newFullName = fullfile(folder,[newPrefix '.mat']);

            if dryRun
                ok = true;
            else
                save(newFullName,'c');
                if isFileNameChange
                    if exist(newFullName,'file')
                        % Only delete once we know the new file exists
                        delete(oldFullName);
                    else
                        ok = false;
                        fprintf('Could not create the new file: %s\n',newFullName)
                    end
                else
                    ok = true; % Assuming the save worked.
                end
            end

            if ok
                if isFileNameChange
                    fprintf('%s Updated %s and renamed to %s\n',dryRunText, oldFullName, newFullName);
                else
                    fprintf('%s Updated %s \n',dryRunText, oldFullName);
                end
            else
                fprintf('Unable to create %s\n',newFullName)
                return;
            end
            if isFileNameChange
                %% Move data files associated with this experiment (same prefix)
                filesWithExperiment= dir(fullfile(folder,"**",oldPrefix + "*"));
                for i=numel(filesWithExperiment):-1:1 % Start at the deepest nested levels (to avoid renaming folders before the files that are in them)
                    thisOldName = fullfile(filesWithExperiment(i).folder,filesWithExperiment(i).name);
                    thisNewName = fullfile(filesWithExperiment(i).folder,strrep(filesWithExperiment(i).name,oldPrefix,newPrefix));
                    if dryRun
                        status=1;
                    else
                        [status, message] = movefile(thisOldName,thisNewName);
                    end
                    if status ==0
                        fprintf(2,'Failed to move %s to %s (%s)\n',thisOldName,thisNewName,message);
                    else
                        fprintf('%s \t Renamed associated file %s to %s\n',dryRunText, filesWithExperiment(i).name, strrep(thisNewName,folder,''));
                    end
                end
                provenance = sprintf('%s@%s',oldPrefix,datetime('now','Format','yyyyMMdd:HHmmSS'));
            else
                provenance = sprintf('Run-%d;Seq-%d@%s',oldRun,oldSeq,datetime('now','Format','yyyyMMdd:HHmmSS'));
            end

            if isSubjectChange
                % Also rename files associated with the subject
                filesWithSubject= dir(fullfile(folder,oldSubject + ".*")); % Not recursive over folders; just the top folder (but including folders)
                % Remove the ones we already renamed above (only relevant during dryun where they would be detected again).
                out = ismember({filesWithSubject.name},{filesWithExperiment.name});
                filesWithSubject(out) =[];
                % Remove files for this subject that are associated with a
                % different experiment
                pattern = ['\<' oldSubject '\.(?<paradigm>\w+)\.(?<starttime>\d{6,6})\.*'];
                out = ~cellfun(@isempty,regexp({filesWithSubject.name},pattern));
                filesWithSubject(out)=[];
                for i=1:numel(filesWithSubject)
                    if startsWith(filesWithSubject(i).name,[oldSubject '.'])
                        thisOldName = fullfile(folder,filesWithSubject(i).name);
                        thisNewName = fullfile(folder,[c.subject '.' extractAfter(filesWithSubject(i).name,[oldSubject '.'])]);
                        if dryRun
                            status=1;
                        else
                            [status, message] = movefile(thisOldName,thisNewName);
                        end
                        if status ==0
                            fprintf(2,'Failed to move %s to %s (%s)\n',thisOldName,thisNewName,message);
                        else
                            fprintf(2,'%s \t .Please chek: renamed associated subject file %s to %s\n',dryRunText, filesWithSubject(i).name, strrep(thisNewName,folder,''));
                        end
                    end
                end
            end


            if dryRun
                fprintf("%s Provenance: \n ",dryRunText)
                provenance %#ok<NOPRT,NOPRT>
            end

            function renameDirWithFiles()

            end
        end

        function addNewMeta(app,target,fields)
            % Add a new meta data field to the meta data.
            % Set the name of the targeted table
            org = ['original' target 'MetaData']; % The clean original
            editable = [target 'Meta'];       % The editable version in the gui
            nrRows = height(app.(org));
            if nrRows <0
                msgbox('No data, nothing to add.')
            end
            for i=1:numel(fields)
                if ismember(fields{i},app.(org).Properties.VariableNames)
                    % Already in table. Skip
                    continue;
                end
                app.(org)= addvars(app.(org),repmat("",[nrRows,1]),'NewVariableNames',fields{i});
                app.(editable).Data = addvars(app.(editable).Data ,repmat("",[nrRows,1]),'NewVariableNames',fields{i});
            end
            refreshUI(app,target);
        end


        function guiState(app,value)

            app.Year.Enable=value;
            app.Month.Enable=value;
            app.Day.Enable=value;
            app.NextDay.Enable =value;
            app.PreviousDay.Enable = value;
            if strcmpi(value,'off')
                app.ExperimentMeta.Data= table;
                app.SessionMeta.Data= table;
                app.SubjectMeta.Data= table;
                app.originalExperimentMetaData = table;
                app.originalSessionMetaData = table;
                app.originalSubjectMetaData = table;
                refreshUI(app)
            end

        end

        function v = selectionToArray(~,selection)
            % Allow users to select multiple paradigms/subjects with a
            % stringl ike 12,34,12  or 'me','joe'
            if contains(selection,',')
                if contains(selection,'''')
                    eval(['v = {' selection '}' ]);
                else
                    eval(['v = {''' strrep(selection,',',''',''') '''}' ]);
                end
            elseif ~isempty(selection)
                v = {selection};
            else
                v = {};
            end
        end
        
        function showMetaDescriptions(~,T)
            msg = [string(T.Properties.VariableNames)',string(T.Properties.VariableDescriptions)'];
            msg(msg(:,1) =="id",:) =[]; % Remove internal ID
            msg =  sprintf('%s:   %s \n',msg');
            helpdlg(msg);            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadState(app);
            % Make sure the top bar is not off-screen
            app.NeurostimMetaDataEditorUIFigure.Position(2)  = min(app.NeurostimMetaDataEditorUIFigure.Position(2), groot().ScreenSize(4)-100-app.NeurostimMetaDataEditorUIFigure.Position(4));
            refreshUI(app,'Experiment');
            refreshUI(app,'DJ');
            app.RootValueChanged;
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'watch';
            drawnow;
            %% Refresh from folder
            [app.originalExperimentMetaData,app.originalSessionMetaData,app.originalSubjectMetaData] = nsScanWrapper(app);
            if isempty(app.originalExperimentMetaData)
                editableData = app.originalExperimentMetaData;
            else
                % Hide provenance and CIC fields from the UITable
                isEditable  = ~ismember(app.originalExperimentMetaData.Properties.VariableNames,{'provenance','cic'});
                editableData  = app.originalExperimentMetaData(:,isEditable); % Editable copy in the UI.
            end
            app.ExperimentMeta.Data =     editableData;
            app.SessionMeta.Data = app.originalSessionMetaData;
            app.SubjectMeta.Data = app.originalSubjectMetaData;

            refreshUI(app)
            app.updateNeeded  =false;
            app.Update.BackgroundColor = app.OKCOLOR;
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Button pushed function: Update
        function UpdatePushed(app, event)
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'watch';
            drawnow;
            if app.DryRunCheckBox.Value
                dryRunText = '[DRYRUN]';
                dryRun  = true;
            else
                dryRun  = false;
                dryRunText  = '';
            end
            fprintf('****** Updating meta data %s *******\n',datetime('now'))

            % Make sure editable and original data are sorted the same way.
            app.ExperimentMeta.Data = sortrows(app.ExperimentMeta.Data,'id');
            app.originalExperimentMetaData = sortrows(app.originalExperimentMetaData,'id');
            % Only store in JSON what is not already in the file or
            % filename
            fieldsInExperimentTable = {'subject','session_date','starttime','paradigm','file','stimuli','blocks','conditions','nrtrials','matlab','ptb','ns','run','seq'};
            jsonFieldsExperiment = setdiff(app.ExperimentMeta.Data.Properties.VariableNames,cat(2,fieldsInExperimentTable,{'id','bytes'}));
            hasRunSeq = ismember('run',app.ExperimentMeta.Data.Properties.VariableNames); % Only available if file contents have been read
            for row=1:height(app.originalExperimentMetaData)
                %% Check (meta) data that are stored in the file and the filename (only subject,paradigm, run and seq are allowed to be changed).
                % and update if needed.
                filename = fullfile(app.Root.Value,strrep(app.originalExperimentMetaData.session_date{row},'-','/'),app.originalExperimentMetaData.file(row));
                if ~strcmpi(app.ExperimentMeta.Data{row,'subject'},app.originalExperimentMetaData{row,'subject'}) ...
                        || ~strcmpi(app.ExperimentMeta.Data{row,'paradigm'},app.originalExperimentMetaData{row,'paradigm'}) ...
                        || (hasRunSeq ...
                        && (app.ExperimentMeta.Data{row,'run'}~=app.originalExperimentMetaData{row,'run'} ...
                        || app.ExperimentMeta.Data{row,'seq'} ~= app.originalExperimentMetaData{row,'seq'}))
                    % update/rename the file and the CIC object it contains (this
                    % will rename json and other associated files too)
                    [filename,newProvenance] = updateFile(app,row);
                    jsonNeedsUpdate = true;
                else
                    %% Check whether the meta data that came from a JSON file changed
                    jsonNeedsUpdate = any(cellfun(@(x) ismember(x,app.originalExperimentMetaData.Properties.VariableNames) && ~isequal(app.originalExperimentMetaData{row,x},app.ExperimentMeta.Data{row,x}),jsonFieldsExperiment));
                    newProvenance = '';
                end

                if jsonNeedsUpdate
                    jsonFullFile = regexprep(filename,'(\.mat$)','.json'); % Swap extension
                    jsonStruct = table2struct(app.ExperimentMeta.Data(row,jsonFieldsExperiment));
                    jsonStruct.provenance = app.originalExperimentMetaData{row,'provenance'}; % provenance is in the original
                    if ~isempty(newProvenance)
                        % Concatenate
                        jsonStruct.provenance = jsonStruct.provenance + "/" +newProvenance;
                    end
                    if dryRun
                        %Nothing to do
                    else
                        writeJson(jsonStruct,jsonFullFile);
                    end
                    fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
                end
            end

            %% Update session data
            app.originalSessionMetaData = sortrows(app.originalSessionMetaData,'id');
            app.SessionMeta.Data = sortrows(app.SessionMeta.Data,'id');
            jsonFieldsSession = setxor(app.SessionMeta.Data.Properties.VariableNames,{'id','session_date','subject'})';
            for row=1:height( app.originalSessionMetaData )
                %% Check whetehr the meta data that came from a JSON file changed
                jsonNeedsUpdate = any(~cellfun(@(x) isequal(app.originalSessionMetaData {row,x},app.SessionMeta.Data{row,x}),jsonFieldsSession));
                if jsonNeedsUpdate
                    jsonFullFile = fullfile(app.Root.Value,strrep(app.SessionMeta.Data.session_date{row},'-','/'),sprintf('%s.json',app.SessionMeta.Data.subject{row}));
                    jsonStruct = table2struct(app.SessionMeta.Data(row,jsonFieldsSession));
                    if dryRun
                        %Nothing to do
                    else
                        writeJson(jsonStruct,jsonFullFile);
                    end
                    fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
                end
            end


            %% Update subject data
            app.originalSubjectMetaData = sortrows(app.originalSubjectMetaData,'id');
            app.SubjectMeta.Data = sortrows(app.SubjectMeta.Data,'id');
            jsonFieldsSubject = setxor(app.SubjectMeta.Data.Properties.VariableNames,{'id'})';
            jsonFullFile = fullfile(app.Root.Value,'subject.json');
            if exist(jsonFullFile,'file')
                jsonSubjects = readJson(jsonFullFile);
            else
                jsonSubjects = struct([]);
            end
            subjectJsonNeedsUpdate = false;
            for row=1:height(app.SubjectMeta.Data)
                %% Check whetehr the meta data that came from a JSON file changed
                jsonNeedsUpdate = any(~cellfun(@(x) isequal(app.originalSubjectMetaData {row,x},app.SubjectMeta.Data{row,x}),jsonFieldsSubject));
                if jsonNeedsUpdate
                    subjectJsonNeedsUpdate = true;
                    thisJson = table2struct(app.SubjectMeta.Data(row,jsonFieldsSubject));
                    if isempty(jsonSubjects)
                        jsonSubjects = thisJson;
                    else
                        ix = [jsonSubjects.subject] == thisJson.subject;
                        if any(ix)
                            jsonSubjects(ix) = [];
                        end
                        if numel(jsonSubjects)==0
                            jsonSubjects =thisJson;
                        else
                            jsonSubjects = catstruct(1,jsonSubjects,thisJson);
                        end
                    end
                end
            end
            if subjectJsonNeedsUpdate
                if dryRun
                    %Nothing to do
                else
                    writeJson(jsonSubjects,jsonFullFile);
                end
                fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
            end

            %% Update the correspondign DataJoint database entries
            if app.DataJointCheckBox.Value
                try
                    currentTargetDatabase= ns.getSchema().dbname;
                catch me
                    errordlg(sprintf('Could not find ns.getSchema from %s. Are you in the right folder?' ,pwd));
                    rethrow(me)
                end

                if strcmpi(currentTargetDatabase,app.previousDatabase)
                    response = 'OK';
                else
                    response = questdlg(sprintf("This update will be added to the %s DataJoint database on %s as %s\n",currentTargetDatabase,app.djHost,app.djUser),"Confirm DJ Target",'OK','No. Stop!','OK');
                end
                if isempty(response) || strcmpi(response,'No. Stop!')
                    fprintf('No changes were made to the database\n')
                else
                    % Connect to the database as specified in the DataJoint
                    % Menu. This will create a persistent object used by
                    % subsequent queries.                    
                    if app.ReadContentMenu.Checked
                        %  Collect the cic objects from the original data
                        %  and pass (they are sorted the same way)
                        cicObjects = app.originalExperimentMetaData.cic;
                    else
                        cicObjects = [];
                    end
                    % Update the database if a corresponding entry already
                    % exists.
                    nsAddToDataJoint(app.SubjectMeta.Data ,app.SessionMeta.Data ,app.ExperimentMeta.Data ,'cic',cicObjects,'safeMode',~strcmpi(app.djSafemode,'0'),'root',app.Root.Value,'dryrun',app.DryRunCheckBox.Value,'populateFile',false,'existingOnly',true);
                    app.previousDatabase = currentTargetDatabase;
                end
            end

            if dryRun
                fprintf('%s No changes were made\n',dryRunText)
            else
                % Force an update to get old and new metadata in sync again.
                app.RefreshPushed;
            end
            fprintf('****** Updating meta data complete *******\n')
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'arrow';
            drawnow;

        end

        % Button pushed function: AddMetaField
        function AddMetaFieldPushed(app, event)
            % Allow user to add a meta data field. Initial values are
            % empty, but can be filled in the GUI and will then be saved
            % (only for the rows that changed from empty).
            newField = inputdlg('Fieldname?','Provide the (lower case) name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Experiment',lower(newField))
            end
        end

        % Close request function: NeurostimMetaDataEditorUIFigure
        function NeurostimMetaDataEditorUIFigureCloseRequest(app, event)
            % Shutting down.
            if  app.updateNeeded
                answer = questdlg('Changes have not been saved.','Unsaved Changes', 'Discard','Update','Update');
                if strcmpi(answer,'Update')
                    app.UpdatePushed;
                end
            end
            saveState(app)
            delete(app)
            if strcmpi(event.EventName,'Reset')
                % Remove preferences
                rmpref('nsMeta');
                % Re-open app
                nsMeta;
            end
        end

        % Menu selected function: ChangeRootFolderMenu
        function ChangeRootFolderMenuSelected(app, event)
            % Convenience function to select a root folder with a  gui.
            folder = uigetdir(app.Root.Value);
            if folder~=0 % folder ==0 is cancel
                app.Root.Value= folder;
            end
            app.RootValueChanged;
        end

        % Button pushed function: AddMetaFieldSession
        function AddMetaFieldSessionButtonPushed(app, event)
            % Allow user to add a meta data field to the Sessions table. Initial values are
            % empty, but can be filled in the GUI and will then be saved
            % (only for the rows that changed from empty).
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Session',newField)
            end
        end

        % Button pushed function: AddMetaFieldSubject
        function AddMetaFieldSubjectButtonPushed(app, event)
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Subject',newField)
            end
        end

        % Button pushed function: NextDay
        function NextDayButtonPushed(app, event)
            %% First check whether an update is needed.
            if  app.updateNeeded
                answer = questdlg('Changes have not been saved.','Unsaved Changes', 'Discard','Update','Update');
                if strcmpi(answer,'Update')
                    app.UpdatePushed;
                end
            end

            % Then try to move to the next day if it exists.
            current = find(ismember(app.Day.Items,app.Day.Value));
            if current < numel(app.Day.Items)
                app.Day.Value = app.Day.Items{current+1};
            else
                % Last day of the month
                currentMonth =  find(ismember(app.Month.Items,app.Month.Value));
                if currentMonth < numel(app.Month.Items)
                    app.Month.Value = app.Month.Items{currentMonth+1};
                    app.MonthValueChanged;
                    app.Day.Value = app.Day.Items{1};
                else
                    % Last month of the year
                    currentYear =  find(ismember(app.Year.Items,app.Year.Value));
                    if currentYear < numel(app.Year.Items)
                        app.Year.Value = app.Year.Items{currentYear+1};
                        app.YearValueChanged;
                        app.Month.Value = app.Month.Items{1};
                        app.MonthValueChanged;
                        app.Day.Value = app.Day.Items{1};
                    else
                        %fprintf('This is the end...\n')
                    end
                end
            end
            app.RefreshPushed
        end

        % Button pushed function: PreviousDay
        function PreviousDayButtonPushed(app, event)
            % Check to see if updates are needed.
            if  app.updateNeeded
                answer = questdlg('Changes have not been saved.','Unsaved Changes', 'Discard','Update','Update');
                if strcmpi(answer,'Update')
                    app.UpdatePushed;
                end
            end
            % Then move to the previous day in the list.
            current = find(ismember(app.Day.Items,app.Day.Value));
            if current >1
                app.Day.Value = app.Day.Items{current-1};
            else
                % First day of the month
                currentMonth =  find(ismember(app.Month.Items,app.Month.Value));
                if currentMonth > 1
                    app.Month.Value = app.Month.Items{currentMonth-1};
                    app.MonthValueChanged;
                    app.Day.Value = app.Day.Items{end};
                else
                    % First month of the year
                    currentYear =  find(ismember(app.Year.Items,app.Year.Value));
                    if currentYear > 1
                        app.Year.Value = app.Year.Items{currentYear-1};
                        app.YearValueChanged;
                        app.Month.Value = app.Month.Items{end};
                        app.MonthValueChanged;
                        app.Day.Value = app.Day.Items{end};
                    else
                        fprintf('This is the beginning ...\n')
                    end
                end
            end
            app.RefreshPushed


        end

        % Value changed function: Root
        function RootValueChanged(app, event)
            % This scans the Root folder recursively to extract
            % Year/Month/Days.
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'watch';
            drawnow;

            root = app.Root.Value;
            app.years ={};
            app.months = {};
            app.days = {};
            yrs = dir([root '/*']);
            yrs = yrs([yrs.isdir]);
            match = regexp({yrs.name},'(?<year>^\d{4,4}$)','match');
            if isempty(match)
                app.guiState('off');
            else
                app.years = [match{:}];
                keepYear = true(size(app.years));
                for yr= 1:numel(app.years)
                    mnths = dir(fullfile(root,[app.years{yr} '/*']));
                    mnths = mnths([mnths.isdir]);
                    match =  regexp({mnths.name},'(?<month>^\d{2,2}$)','match');
                    mnths = [match{:}];
                    if isempty(mnths)
                        keepYear(yr)= false;
                        continue;
                    else
                        app.months{yr} = mnths;
                    end
                    keepMonth= true(size(app.months{yr}));
                    for mo = 1:numel(app.months{yr})
                        dys= dir(fullfile(root,app.years{yr},[app.months{yr}{mo} '/*']));
                        dys = dys([dys.isdir]);
                        match =  regexp({dys.name},'(?<month>^\d{2,2}$)','match');
                        dys = [match{:}];
                        if isempty(dys)
                            keepMonth(mo)=false;
                        else
                            keepDays = true(size(dys));
                            if  app.ScanFilesMenu.Checked
                                % Time consuming, so optional.
                                for dy=1:numel(dys)
                                    keepDays(dy) = ~isempty(dir(fullfile(root,app.years{yr},app.months{yr}{mo},[dys{dy} '/*.mat'])));
                                end
                            end
                            dys =dys(keepDays);
                            if isempty(dys)
                                keepMonth(mo) =false;
                            else
                                app.days{yr}{mo} = dys;
                            end
                        end
                    end
                    if any(keepMonth)
                        app.days{yr} = app.days{yr}(keepMonth);
                        app.months{yr}= app.months{yr}(keepMonth);
                    else
                        keepYear(yr)= false;
                    end

                end
                app.years = app.years(keepYear);
                app.months = app.months(keepYear);
                app.days = app.days(keepYear);
                app.guiState('on');
                app.Year.Items = app.years;
                app.Year.Value = app.years(end);
                YearValueChanged(app, [])

            end
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'arrow';
            drawnow;

        end

        % Value changed function: Year
        function YearValueChanged(app, event)
            value = app.Year.Value;
            app.Month.Items = app.months{ismember(app.years,value)};
            app.Month.Value  = app.Month.Items{end};
            app.MonthValueChanged;
        end

        % Value changed function: Month
        function MonthValueChanged(app, event)

            yrIx = ismember(app.years,app.Year.Value);
            moIx = ismember(app.months{yrIx},app.Month.Value);
            app.Day.Items = app.days{yrIx}{moIx};
            app.Day.Value = app.Day.Items{end};
            app.DayValueChanged;
        end

        % Value changed function: Day
        function DayValueChanged(app, event)
            app.Refresh.BackgroundColor = app.WARNCOLOR; % Signal need to refresh

        end

        % Cell edit callback: ExperimentMeta, SessionMeta, SubjectMeta
        function MetaDataCellEdit(app, event)
            if event.NewData~=event.PreviousData
                app.updateNeeded = true;
                app.Update.BackgroundColor = app.WARNCOLOR;
            end
        end

        % Menu selected function: ScanFilesMenu
        function ScanFilesMenuSelected(app, event)
            % Toggle and rescan
            if app.ScanFilesMenu.Checked
                app.ScanFilesMenu.Checked =false;
            else
                app.ScanFilesMenu.Checked =true;
            end
            app.RootValueChanged;

        end

        % Value changed function: Paradigm
        function ParadigmValueChanged(app, event)
            app.RefreshPushed;
        end

        % Value changed function: Subject
        function SubjectValueChanged(app, event)
            app.RefreshPushed;
        end

        % Button down function: ExperimentMeta
        function ExperimentMetaButtonDown(app, event)

        end

        % Key release function: NeurostimMetaDataEditorUIFigure
        function NeurostimMetaDataEditorUIFigureKeyRelease(app, event)
            %key = event.Key;

        end

        % Key release function: ExperimentMeta
        function ExperimentMetaKeyRelease(app, event)
            key = event.Key;
            switch upper(key)
                case {'R','F'}
                    % Read the file and put in 'c' in the base workspace.
                    id = app.ExperimentMeta.Data{app.ExperimentMeta.Selection(1),'id'};
                    % Find the row in the original data in case there is a
                    % unsaved change in the metadata that determine the
                    % filename
                    row = find(app.originalExperimentMetaData.id==id);
                    fname= app.originalExperimentMetaData.file(row);
                    fldr = strrep(app.originalExperimentMetaData.session_date(row),'-','/');
                    fname =fullfile(app.originalExperimentMetaData.Properties.CustomProperties.root,fldr,fname);
                    if exist(fname,'file')
                        load(fname,'c');
                        assignin('base',"c",c);
                        fprintf('%s is now assigned to variable ''c''\n',fname)
                        if strcmpi(key,'F') && ~isempty(app.Fun.Value)
                            evalin('base',app.Fun.Value)
                        end
                    else
                        fprintf(2,'%s does not exist?\n',fname)
                    end
            end

        end

        % Menu selected function: DataJointMenu
        function DataJointMenuSelected(app, event)
            prompt = {'Host:','User:','Pass:','Safemode:'};
            title = 'DataJoint Server Settings';
            dims = [1 20];
            current = {app.djHost,app.djUser,app.djPass,app.djSafemode};
            answer = inputdlg(prompt,title,dims,current);
            if ~isempty(answer)
                app.djHost = answer{1};
                app.djUser = answer{2};
                app.djPass = answer{3};
                app.djSafemode = answer{4};
                if exist('dj.conn','file')
                    clear dj.conn  % Force a re-connect on the database (as host/user may have changed).
                end
            end
            refreshUI(app,'DJ');
        end

        % Value changed function: DataJointCheckBox
        function DataJointCheckBoxValueChanged(app, event)
            if app.DataJointCheckBox.Value
                app.DataJointLabel.Visible = "on";
            else
                app.DataJointLabel.Visible = "off";
            end
            refreshUI(app,'DJ')
        end

        % Double-clicked callback: SessionMeta
        function SessionMetaDoubleClicked(app, event)
            col= event.InteractionInformation.DisplayColumn;
            if col~=1;return;end % Only respond to double click on the ID field
            row = event.InteractionInformation.DisplayRow;

            jsonFile = fullfile(app.Root.Value,strrep(event.Source.Data{row,'session_date'},'-','/'),event.Source.Data{row,'subject'} + ".json");
            if exist(jsonFile,"file")
                edit(jsonFile)
            else
                warndlg(sprintf('No JSON file found (%s)',jsonFile))
            end
        end

        % Double-clicked callback: SubjectMeta
        function SubjectMetaDoubleClicked(app, event)
            col= event.InteractionInformation.DisplayColumn;
            if col~=1;return;end % Only respond to double click on the ID field

            jsonFile = fullfile(app.Root.Value,'subject.json');
            if exist(jsonFile,"file")
                edit(jsonFile)
            else
                warndlg(sprintf('No JSON file found (%s)',jsonFile))
            end
        end

        % Double-clicked callback: ExperimentMeta
        function ExperimentMetaDoubleClicked(app, event)
            col= event.InteractionInformation.DisplayColumn;
            if col~=1;return;end % Only respond to double click on the ID field

            row = event.InteractionInformation.DisplayRow;
            [~,file] = fileparts(event.Source.Data{row,'file'});
            jsonFile = fullfile(app.Root.Value,strrep(event.Source.Data{row,'session_date'},'-','/'),file + ".json");
            if exist(jsonFile,"file")
                edit(jsonFile)
            else
                warndlg(sprintf('No JSON file found (%s)',jsonFile))
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            fldr = fileparts(mfilename("fullpath"));
            open(fullfile(fldr,'README.md'))
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            NeurostimMetaDataEditorUIFigureCloseRequest(app,struct('EventName','Reset'))
        end

        % Menu selected function: ReadContentMenu
        function ReadContentMenuSelected(app, event)
            if app.ReadContentMenu.Checked
                app.ReadContentMenu.Checked =false;
            else
                app.ReadContentMenu.Checked =true;
            end
            app.RefreshPushed
        end

        % Button down function: SubjectsTab
        function SubjectsTabButtonDown(app, event)

            % First read the definition
            definitionFile = fullfile(app.Root.Value,['subject_definition' app.MetaDefinitionTag.Value  '.json']);
            if exist(definitionFile,'file')
                json = readJson(definitionFile);
                if json.Locked =="1" % No new meta fields allowed according to _definition
                    app.AddMetaFieldAllSubjects.Enable = "off";
                else
                    app.AddMetaFieldAllSubjects.Enable = "on";
                end
                subjectMetaFields = fieldnames(json.Fields);
                if ismember('subject',subjectMetaFields)
                    error('The subject meta definition file %s should only define new meta properties, not ''subject''',definitionFile);
                end
            elseif isempty(metaDefinitionTag)
                subjectMetaFields="";
            end

            % Read the data from the subjects json file, show as table
            jsonFullFile = fullfile(app.Root.Value,'subject.json');
            if exist(jsonFullFile,'file')
                app.originalAllSubjectsMetaData = sortrows(struct2table(readJson(jsonFullFile)),"subject");
            else
                % No subject data yet, create an empty dtable
                subject = "";comments="";
                app.originalAllSubjectsMetaData=table(subject,comments);
            end
            notDefined = setdiff(subjectMetaFields,app.originalAllSubjectsMetaData.Properties.VariableNames);

            if ~isempty(notDefined)
                noValue = cell(1,numel(notDefined));
                [noValue{:}]=deal(strings(height(app.originalAllSubjectsMetaData), 1));
                app.originalAllSubjectsMetaData = addvars(app.originalAllSubjectsMetaData,noValue{:},'NewVariableNames',notDefined);
            end

            app.originalAllSubjectsMetaData = movevars(app.originalAllSubjectsMetaData,"comments","After",width(app.originalAllSubjectsMetaData));
            app.originalAllSubjectsMetaData = movevars(app.originalAllSubjectsMetaData,"subject","Before",1);
            app.AllSubjectsMeta.Data = app.originalAllSubjectsMetaData;
            if isempty(app.AllSubjectsMeta.Data)
                app.AllSubjectsMeta.ColumnName = {'No subject meta data found.'};
            else
                app.AllSubjectsMeta.ColumnName = app.AllSubjectsMeta.Data.Properties.VariableNames;
                allTrue = true(1,width(app.AllSubjectsMeta.Data));
                app.AllSubjectsMeta.ColumnSortable = allTrue;
                app.AllSubjectsMeta.ColumnEditable = allTrue  & ~ismember(app.AllSubjectsMeta.Data.Properties.VariableNames,{'subject'});
                app.AllSubjectsMeta.ColumnWidth = repmat({'fit'},[1 width(app.AllSubjectsMeta.Data)]);
            end


        end

        % Button pushed function: AddMetaFieldAllSubjects
        function AddMetaFieldAllSubjectsButtonPushed(app, event)
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'AllSubjects',newField)
            end
        end

        % Button pushed function: AllSubjectsUpdate
        function AllSubjectsUpdateButtonPushed(app, event)
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'watch';
            drawnow;
            if app.AllSubjectsDryRunCheckBox.Value
                dryRunText = '[DRYRUN]';
                dryRun  = true;
            else
                dryRun  = false;
                dryRunText  = '';
            end
            fprintf('****** Updating subject meta data %s *******\n',datetime('now'))

            %% Update subject data
            jsonFullFile = fullfile(app.Root.Value,'subject.json');            
            jsonSubjects = table2struct(app.AllSubjectsMeta.Data);
                if dryRun
                    %Nothing to do
                else
                    writeJson(jsonSubjects,jsonFullFile);
                end
                fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
        
            if dryRun
                fprintf('%s No changes were made\n',dryRunText)
            else
                app.allSubjectsUpdateNeeded = false;
                app.AllSubjectsUpdate.BackgroundColor = app.OKCOLOR;
            end
            fprintf('****** Updating subject meta data complete *******\n')

            app.NeurostimMetaDataEditorUIFigure.Pointer = 'arrow';
            drawnow;

        end

        % Button down function: DataTab
        function DataTabButtonDown(app, event)
            if  app.updateNeeded
                answer = questdlg('Changes have not been saved.','Unsaved Changes', 'Discard','Update','Update');
                if strcmpi(answer,'Update')
                    app.UpdatePushed;
                end
            end
        end

        % Cell edit callback: AllSubjectsMeta
        function AllSubjectsMetaCellEdit(app, event)
            if event.NewData~=event.PreviousData
                app.allSubjectsUpdateNeeded = true;
                app.AllSubjectsUpdate.BackgroundColor= app.WARNCOLOR;
            end
        end

        % Button pushed function: AddSubject
        function AddSubjectButtonPushed(app, event)
            noValue  = cell(1,width(app.AllSubjectsMeta.Data));
            [noValue{:}] = deal("");
            placeholder =  table(noValue{:},'VariableNames',app.AllSubjectsMeta.Data.Properties.VariableNames);
            placeholder.subject= inputdlg('Subject ID?','Provide subject ID.');           
            app.AllSubjectsMeta.Data = [app.AllSubjectsMeta.Data;placeholder];
           app.allSubjectsUpdateNeeded = true;
                app.AllSubjectsUpdate.BackgroundColor= app.WARNCOLOR; 
        end

        % Button pushed function: DeleteSubject
        function DeleteSubjectButtonPushed(app, event)
            if isempty(app.AllSubjectsMeta.Selection)
                warndlg('No subject selected. Nothing to delete.')
            else
                rowsSelected = app.AllSubjectsMeta.Selection(:,1);
                app.AllSubjectsMeta.Data(rowsSelected,:) = [];
                app.allSubjectsUpdateNeeded = true;
                app.AllSubjectsUpdate.BackgroundColor= app.WARNCOLOR;
            end
        end

        % Button pushed function: showExpMetaDefs
        function showExpMetaDefsButtonPushed(app, event)
           showMetaDescriptions(app,app.ExperimentMeta.Data)
        end

        % Button pushed function: showExpSessionMetaDefs
        function showExpSessionMetaDefsButtonPushed(app, event)
             showMetaDescriptions(app,app.SessionMeta.Data)
        end

        % Button pushed function: showSubjectMetaDefs
        function showSubjectMetaDefsButtonPushed(app, event)
             showMetaDescriptions(app,app.SubjectMeta.Data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeurostimMetaDataEditorUIFigure = uifigure('Visible', 'off');
            app.NeurostimMetaDataEditorUIFigure.Name = 'Neurostim Meta Data Editor';
            app.NeurostimMetaDataEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeurostimMetaDataEditorUIFigureCloseRequest, true);
            app.NeurostimMetaDataEditorUIFigure.KeyReleaseFcn = createCallbackFcn(app, @NeurostimMetaDataEditorUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.NeurostimMetaDataEditorUIFigure);
            app.FileMenu.Text = 'File';
            app.ChangeRootFolderMenu = uimenu(app.FileMenu);
            app.ChangeRootFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeRootFolderMenuSelected, true);
            app.ChangeRootFolderMenu.Tooltip = {'Use a dialog box to select the root folder.'};
            app.ChangeRootFolderMenu.Accelerator = 'R';
            app.ChangeRootFolderMenu.Text = 'Change Root Folder';
            app.ScanFilesMenu = uimenu(app.FileMenu);
            app.ScanFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanFilesMenuSelected, true);
            app.ScanFilesMenu.Tooltip = {'Set this to true to scan down to the file level when determining which folders have data. This is a slower initial search , but will list only folders with data in the Year/Month/Day drop down menus. '};
            app.ScanFilesMenu.Accelerator = 'S';
            app.ScanFilesMenu.Text = 'Scan Files';
            app.ReadContentMenu = uimenu(app.FileMenu);
            app.ReadContentMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadContentMenuSelected, true);
            app.ReadContentMenu.Tooltip = {'Select this to read the contents of the Neurostim files to show additional information (run, seq,trials, blocks). This can be time consuming.  '};
            app.ReadContentMenu.Text = 'Read Contents';
            app.DataJointMenu = uimenu(app.FileMenu);
            app.DataJointMenu.MenuSelectedFcn = createCallbackFcn(app, @DataJointMenuSelected, true);
            app.DataJointMenu.Visible = 'off';
            app.DataJointMenu.Enable = 'off';
            app.DataJointMenu.Accelerator = 'D';
            app.DataJointMenu.Text = 'DataJoint';
            app.ResetMenu = uimenu(app.FileMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Tooltip = {'Reset the app to facotry defaults.'};
            app.ResetMenu.Text = 'Reset';
            app.HelpMenu = uimenu(app.NeurostimMetaDataEditorUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.NeurostimMetaDataEditorUIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataTab.ButtonDownFcn = createCallbackFcn(app, @DataTabButtonDown, true);
            app.ExperimentMeta = uitable(app.DataTab);
            app.ExperimentMeta.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ExperimentMeta.RowName = {};
            app.ExperimentMeta.CellEditCallback = createCallbackFcn(app, @MetaDataCellEdit, true);
            app.ExperimentMeta.DoubleClickedFcn = createCallbackFcn(app, @ExperimentMetaDoubleClicked, true);
            app.ExperimentMeta.Tooltip = {''};
            app.ExperimentMeta.ButtonDownFcn = createCallbackFcn(app, @ExperimentMetaButtonDown, true);
            app.ExperimentMeta.KeyReleaseFcn = createCallbackFcn(app, @ExperimentMetaKeyRelease, true);
            app.AddMetaField = uibutton(app.DataTab, 'push');
            app.AddMetaField.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldPushed, true);
            app.AddMetaField.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddMetaField.Text = 'Add Meta Field';
            app.ExperimentsLabel = uilabel(app.DataTab);
            app.ExperimentsLabel.Text = 'Experiments';
            app.SessionsLabel = uilabel(app.DataTab);
            app.SessionsLabel.Text = 'Sessions';
            app.SessionMeta = uitable(app.DataTab);
            app.SessionMeta.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SessionMeta.RowName = {};
            app.SessionMeta.CellEditCallback = createCallbackFcn(app, @MetaDataCellEdit, true);
            app.SessionMeta.DoubleClickedFcn = createCallbackFcn(app, @SessionMetaDoubleClicked, true);
            app.SessionMeta.Tooltip = {''};
            app.AddMetaFieldSession = uibutton(app.DataTab, 'push');
            app.AddMetaFieldSession.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldSessionButtonPushed, true);
            app.AddMetaFieldSession.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddMetaFieldSession.Text = 'Add Meta Field';
            app.SubjectsLabel = uilabel(app.DataTab);
            app.SubjectsLabel.Text = 'Subjects';
            app.SubjectMeta = uitable(app.DataTab);
            app.SubjectMeta.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SubjectMeta.RowName = {};
            app.SubjectMeta.CellEditCallback = createCallbackFcn(app, @MetaDataCellEdit, true);
            app.SubjectMeta.DoubleClickedFcn = createCallbackFcn(app, @SubjectMetaDoubleClicked, true);
            app.SubjectMeta.Tooltip = {''};
            app.AddMetaFieldSubject = uibutton(app.DataTab, 'push');
            app.AddMetaFieldSubject.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldSubjectButtonPushed, true);
            app.AddMetaFieldSubject.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddMetaFieldSubject.Text = 'Add Meta Field';
            app.SubjectEditFieldLabel = uilabel(app.DataTab);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.Subject = uieditfield(app.DataTab, 'text');
            app.Subject.ValueChangedFcn = createCallbackFcn(app, @SubjectValueChanged, true);
            app.Subject.Tooltip = {'Restrict to a single subject, or a comma separated list of subjects  (Optional).'};
            app.RootLabel = uilabel(app.DataTab);
            app.RootLabel.Text = 'Root';
            app.Root = uieditfield(app.DataTab, 'text');
            app.Root.ValueChangedFcn = createCallbackFcn(app, @RootValueChanged, true);
            app.Root.Tooltip = {'The root folder for all data. (It contains the folders named after the years). '};
            app.Root.Value = 'c:/temp/';
            app.ParadigmEditFieldLabel = uilabel(app.DataTab);
            app.ParadigmEditFieldLabel.Text = 'Paradigm';
            app.Paradigm = uieditfield(app.DataTab, 'text');
            app.Paradigm.ValueChangedFcn = createCallbackFcn(app, @ParadigmValueChanged, true);
            app.Paradigm.Tooltip = {'Restrict to a specific paradigm, or a comma separated list of paradigms (Optional).'};
            app.MetaLabel = uilabel(app.DataTab);
            app.MetaLabel.Text = 'Meta ';
            app.MetaDefinitionTag = uieditfield(app.DataTab, 'text');
            app.MetaDefinitionTag.Tooltip = {'Specify a tag (usually a project name) that defines the meta data. Defaults to empty for the global meta data definition. '};
            app.FunEditFieldLabel = uilabel(app.DataTab);
            app.FunEditFieldLabel.Text = 'Fun';
            app.Fun = uieditfield(app.DataTab, 'text');
            app.Fun.Tooltip = {'After pressing ''F'', this code will execute on the command line after loading the data from the selected experiment as ''c''. For isntance, c.trial  will show the last trial number in this Experiment. To only load the file without executing code, press ''R''; that will make it available on the command line as ''c''.'};
            app.ChangePanel = uipanel(app.DataTab);
            app.ChangePanel.Title = 'Change';
            app.Update = uibutton(app.ChangePanel, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdatePushed, true);
            app.Update.Tooltip = {'Click to write the changed meta data to disk. '; 'This may move and change data files. '; ''; 'When this button is green, an update is needed (or changes will be discarded on the next refresh).'};
            app.Update.Text = 'Update';
            app.DryRunCheckBox = uicheckbox(app.ChangePanel);
            app.DryRunCheckBox.Tooltip = {'Select this and then press update to get a list of changes that would be made.'};
            app.DryRunCheckBox.Text = 'Dry Run';
            app.DataJointCheckBox = uicheckbox(app.ChangePanel);
            app.DataJointCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataJointCheckBoxValueChanged, true);
            app.DataJointCheckBox.Tooltip = {'Select this to commit changes to the DataJoint server.'};
            app.DataJointCheckBox.Text = 'DataJoint';
            app.BrowsePanel = uipanel(app.DataTab);
            app.BrowsePanel.Title = 'Browse';
            app.YearDropDownLabel = uilabel(app.BrowsePanel);
            app.YearDropDownLabel.Text = 'Year';
            app.Year = uidropdown(app.BrowsePanel);
            app.Year.Items = {};
            app.Year.ValueChangedFcn = createCallbackFcn(app, @YearValueChanged, true);
            app.Year.Tooltip = {'Pick a year (data will not update automatically; use the refresh button).'};
            app.Year.Value = {};
            app.MonthLabel = uilabel(app.BrowsePanel);
            app.MonthLabel.Text = 'Month';
            app.Month = uidropdown(app.BrowsePanel);
            app.Month.Items = {};
            app.Month.ValueChangedFcn = createCallbackFcn(app, @MonthValueChanged, true);
            app.Month.Tooltip = {'Pick a month (data will not update automatically; use the refresh button).'};
            app.Month.Value = {};
            app.DayLabel = uilabel(app.BrowsePanel);
            app.DayLabel.Text = 'Day';
            app.Day = uidropdown(app.BrowsePanel);
            app.Day.Items = {};
            app.Day.ValueChangedFcn = createCallbackFcn(app, @DayValueChanged, true);
            app.Day.Tooltip = {'Pick a day (data will not update automatically; use the refresh button).'};
            app.Day.Value = {};
            app.PreviousDay = uibutton(app.BrowsePanel, 'push');
            app.PreviousDay.ButtonPushedFcn = createCallbackFcn(app, @PreviousDayButtonPushed, true);
            app.PreviousDay.Tooltip = {'Go to the previous day (data will update automatically).'};
            app.PreviousDay.Text = '<';
            app.Refresh = uibutton(app.BrowsePanel, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Tooltip = {'Click to refresh the list. When this is green, a refresh is needed. '};
            app.Refresh.Text = 'Refresh';
            app.NextDay = uibutton(app.BrowsePanel, 'push');
            app.NextDay.ButtonPushedFcn = createCallbackFcn(app, @NextDayButtonPushed, true);
            app.NextDay.Tooltip = {'Go to the next day (data will update automatically).'};
            app.NextDay.Text = '>';
            app.showExpMetaDefs = uibutton(app.DataTab, 'push');
            app.showExpMetaDefs.ButtonPushedFcn = createCallbackFcn(app, @showExpMetaDefsButtonPushed, true);
            app.showExpMetaDefs.Tooltip = {'Show definitions of the meta data.'};
            app.showExpMetaDefs.Text = '?';
            app.showExpSessionMetaDefs = uibutton(app.DataTab, 'push');
            app.showExpSessionMetaDefs.ButtonPushedFcn = createCallbackFcn(app, @showExpSessionMetaDefsButtonPushed, true);
            app.showExpSessionMetaDefs.Tooltip = {'Show definitions of the meta data.'};
            app.showExpSessionMetaDefs.Text = '?';
            app.showSubjectMetaDefs = uibutton(app.DataTab, 'push');
            app.showSubjectMetaDefs.ButtonPushedFcn = createCallbackFcn(app, @showSubjectMetaDefsButtonPushed, true);
            app.showSubjectMetaDefs.Tooltip = {'Show definitions of the meta data.'};
            app.showSubjectMetaDefs.Text = '?';
            app.DataJointLabel = uilabel(app.DataTab);
            app.DataJointLabel.Visible = 'off';
            app.DataJointLabel.Tooltip = {'DataJoint Server Connection. Change these in the File | DataJoint Menu'};
            app.DJLabel = uilabel(app.DataTab);
            app.DJLabel.Text = 'DJ';
            app.SubjectsTab = uitab(app.TabGroup);
            app.SubjectsTab.Title = 'Subjects';
            app.SubjectsTab.ButtonDownFcn = createCallbackFcn(app, @SubjectsTabButtonDown, true);
            app.AllSubjectsLabel = uilabel(app.SubjectsTab);
            app.AllSubjectsLabel.Text = 'Subjects';
            app.AllSubjectsMeta = uitable(app.SubjectsTab);
            app.AllSubjectsMeta.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AllSubjectsMeta.RowName = {};
            app.AllSubjectsMeta.CellEditCallback = createCallbackFcn(app, @AllSubjectsMetaCellEdit, true);
            app.AllSubjectsMeta.Tooltip = {''};
            app.AddMetaFieldAllSubjects = uibutton(app.SubjectsTab, 'push');
            app.AddMetaFieldAllSubjects.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldAllSubjectsButtonPushed, true);
            app.AddMetaFieldAllSubjects.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddMetaFieldAllSubjects.Text = 'Add Meta Field';
            app.AllSubjectsUpdate = uibutton(app.SubjectsTab, 'push');
            app.AllSubjectsUpdate.ButtonPushedFcn = createCallbackFcn(app, @AllSubjectsUpdateButtonPushed, true);
            app.AllSubjectsUpdate.Tooltip = {'Click to write the changed meta data to disk. '; 'This may move and change data files. '; ''; 'When this button is green, an update is needed (or changes will be discarded on the next refresh).'};
            app.AllSubjectsUpdate.Text = 'Update';
            app.AllSubjectsDryRunCheckBox = uicheckbox(app.SubjectsTab);
            app.AllSubjectsDryRunCheckBox.Tooltip = {'Select this and then press update to get a list of changes that would be made.'};
            app.AllSubjectsDryRunCheckBox.Text = 'Dry Run';
            app.DeleteSubject = uibutton(app.SubjectsTab, 'push');
            app.DeleteSubject.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubjectButtonPushed, true);
            app.DeleteSubject.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.DeleteSubject.Text = 'Delete Subject';
            app.AddSubject = uibutton(app.SubjectsTab, 'push');
            app.AddSubject.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubject.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddSubject.Text = 'Add Subject';
            % Show the figure after all components are created
            app.NeurostimMetaDataEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nsMeta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeurostimMetaDataEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NeurostimMetaDataEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeurostimMetaDataEditorUIFigure)
        end
    end
end